classdef ProjektSynchrofazory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AkcjeMenu           matlab.ui.container.Menu
        DodajurzdzenieMenu  matlab.ui.container.Menu
        UsuurzdzenieMenu    matlab.ui.container.Menu
        MapaMenu            matlab.ui.container.Menu
        UstawieniaMenu      matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        TabGroup            matlab.ui.container.TabGroup
        WykresykoloweTab    matlab.ui.container.Tab
        GridForPlots        matlab.ui.container.GridLayout
        WidokszczegowyTab   matlab.ui.container.Tab
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout7         matlab.ui.container.GridLayout
        ANALOGLabel         matlab.ui.control.Label
        ANALOG_BUTTON       matlab.ui.control.Button
        GridLayout6         matlab.ui.container.GridLayout
        PHASELabel          matlab.ui.control.Label
        PHASE_BUTTON        matlab.ui.control.Button
        GridLayout5         matlab.ui.container.GridLayout
        MAGNITUDELabel      matlab.ui.control.Label
        MAGNITUDE_BUTTON    matlab.ui.control.Button
        GridLayout4         matlab.ui.container.GridLayout
        DFREQ_BUTTON        matlab.ui.control.Button
        DFREQLabel          matlab.ui.control.Label
        GridLayout3         matlab.ui.container.GridLayout
        FREQ_BUTTON         matlab.ui.control.Button
        FREQLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        DataProvider;
        RefreshRate=15;
        Timer % Used to refresh plots every x seconds
        OptionsDialog UstawieniaDialog% Okno dialogowe dla zmiany ustawień
        PlotOptionsDialog PolarPlotOptions
        AddDialog DodajUrzadzenieDialog
        DeleteDialog UsunUrzadzenieDialog
        DeviceDetailsDialog DeviceDetailsDialog
        MapDialog MapDialog
        FREQ_figure 
        DFREQ_figure 
        MAGNITUDE_figure
        PHASE_figure 
        ANALOG_figure
    end
   
    methods (Access = public)
          
        function LoadFiles(~)
            %Used to include other files
            addpath("enums\");
            addpath("functions\");
            addpath("functions\UsunUrzadzenieDialog\");
            addpath("functions\UstawieniaDialog\");
            addpath("functions\ProjektSynchrofazory\");
            addpath("functions\ProjektSynchrofazory\TimerUpdates\");
            addpath("functions\PolarPlotOptions\");
            addpath("functions\MapDialog\");
            addpath("functions\DodajUrzadzenieDialog\");
            addpath("functions\DeviceDetailsDialog\");
            addpath("enums\command\");
            addpath("enums\frame\");
            addpath("enums\config\");
            addpath("enums\data\");
            addpath("models\");
            addpath("models\frame\");
            addpath("helpers\");
            addpath("dialogs\");
            addpath("repository\");
            addpath("controls\");
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadFiles();%includes other files
            handle = ListOfPMU(app);%create handle for ListOfPMU class
            app.DataProvider=handle;
            if isfile("previousSession.mat")
                previous=load("previousSession.mat");
                if ~isempty(previous.pmus)
                    for i=1:size(previous.pmus,2)
                        app.DataProvider.AddNewPMU(previous.pmus(i));
                    end
                end
                if ~isempty(previous.path)
                    app.DataProvider.pathToDatabase=previous.path;
                end
                if ~isempty(previous.rate)
                    app.RefreshRate=previous.rate;
                end
            end
            app.DataProvider.openDataBase();
            app.Timer=InitTimer(app);%create timer
            start(app.Timer);%start timer

        end

        % Menu selected function: MapaMenu
        function MapaMenuSelected(app, event)
            delete(app.MapDialog);
            if size(app.DataProvider.ListPMU)>0
                app.MapDialog = MapDialog(app);%open map dialog
            end   
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if  ishandle(app.Timer)
                if isvalid(app.Timer)
                    stop(app.Timer);
                end
            end
            delete(app.Timer);
            delete(app.OptionsDialog);
            delete(app.AddDialog);
            k=keys(app.DataProvider.ListPMU);
            pmus=PMU.empty;
            for j=1:size(app.DataProvider.ListPMU)
               key=uint32(cell2mat(k(j)));
               handleToPMU=app.DataProvider.ListPMU(key);
               pmus(end+1)=PMU(handleToPMU.LocalHost,handleToPMU.LocalPort,handleToPMU.ID,"CommunicationType",handleToPMU.CommunicationType);
            end
            rate=app.RefreshRate;
            path=app.DataProvider.pathToDatabase;
            save("previousSession.mat","pmus","rate","path");
            delete(app.DataProvider);
            delete(app.DeleteDialog);
            delete(app.PlotOptionsDialog);
            delete(app)
        end

        % Menu selected function: UstawieniaMenu
        function UstawieniaMenuSelected(app, event)
            delete(app.OptionsDialog);
            app.OptionsDialog = UstawieniaDialog(app);%open options dialog
        end

        % Menu selected function: DodajurzdzenieMenu
        function DodajurzdzenieMenuSelected(app, event)
            delete(app.AddDialog);
            if size(app.DataProvider.PolarPlotsMap)<=16
                app.AddDialog = DodajUrzadzenieDialog(app);%open add new device dialog
            end
        end

        % Menu selected function: UsuurzdzenieMenu
        function UsuurzdzenieMenuSelected(app, event)
            delete(app.DeleteDialog);
            if size(app.DataProvider.ListPMU)>0
                app.DeleteDialog = UsunUrzadzenieDialog(app);%open delete device dialog
            end
        end

        % Button pushed function: FREQ_BUTTON
        function FREQ_BUTTONButtonPushed(app, event)
            delete(app.FREQ_figure);
            app.FREQ_figure = figure("Name","FREQ");%open FREQ figure
        end

        % Button pushed function: DFREQ_BUTTON
        function DFREQ_BUTTONButtonPushed(app, event)
            delete(app.DFREQ_figure);
            app.DFREQ_figure = figure("Name","DFREQ");%open DFREQ figure
        end

        % Button pushed function: MAGNITUDE_BUTTON
        function MAGNITUDE_BUTTONButtonPushed(app, event)
            delete(app.MAGNITUDE_figure);
            app.MAGNITUDE_figure = figure("Name","MAGNITUDE");%open MAGNITUDE figure
        end

        % Button pushed function: PHASE_BUTTON
        function PHASE_BUTTONButtonPushed(app, event)
            delete(app.PHASE_figure);
            app.PHASE_figure = figure("Name","PHASE");%open PHASE figure
        end

        % Button pushed function: ANALOG_BUTTON
        function ANALOG_BUTTONButtonPushed(app, event)
            delete(app.ANALOG_figure);
            app.ANALOG_figure = figure("Name","ANALOG");%open ANALOG figure
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AkcjeMenu = uimenu(app.UIFigure);
            app.AkcjeMenu.Text = 'Akcje';
            app.DodajurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.DodajurzdzenieMenu.MenuSelectedFcn = createCallbackFcn(app, @DodajurzdzenieMenuSelected, true);
            app.DodajurzdzenieMenu.Text = 'Dodaj urządzenie';
            app.UsuurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.UsuurzdzenieMenu.MenuSelectedFcn = createCallbackFcn(app, @UsuurzdzenieMenuSelected, true);
            app.UsuurzdzenieMenu.Text = 'Usuń urządzenie';
            app.MapaMenu = uimenu(app.UIFigure);
            app.MapaMenu.MenuSelectedFcn = createCallbackFcn(app, @MapaMenuSelected, true);
            app.MapaMenu.Text = 'Mapa';
            app.UstawieniaMenu = uimenu(app.UIFigure);
            app.UstawieniaMenu.MenuSelectedFcn = createCallbackFcn(app, @UstawieniaMenuSelected, true);
            app.UstawieniaMenu.Text = 'Ustawienia';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WykresykoloweTab = uitab(app.TabGroup);
            app.WykresykoloweTab.Title = 'Wykresy kołowe';
            app.GridForPlots = uigridlayout(app.WykresykoloweTab);
            app.WidokszczegowyTab = uitab(app.TabGroup);
            app.WidokszczegowyTab.Title = 'Widok szczegółowy';
            app.GridLayout2 = uigridlayout(app.WidokszczegowyTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.FREQLabel = uilabel(app.GridLayout3);
            app.FREQLabel.Text = 'FREQ';
            app.FREQ_BUTTON = uibutton(app.GridLayout3, 'push');
            app.FREQ_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @FREQ_BUTTONButtonPushed, true);
            app.FREQ_BUTTON.Text = 'Otwórz wykres';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.DFREQLabel = uilabel(app.GridLayout4);
            app.DFREQLabel.Text = 'DFREQ';
            app.DFREQ_BUTTON = uibutton(app.GridLayout4, 'push');
            app.DFREQ_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @DFREQ_BUTTONButtonPushed, true);
            app.DFREQ_BUTTON.Text = 'Otwórz wykres';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.MAGNITUDE_BUTTON = uibutton(app.GridLayout5, 'push');
            app.MAGNITUDE_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @MAGNITUDE_BUTTONButtonPushed, true);
            app.MAGNITUDE_BUTTON.Text = 'Otwórz wykres';
            app.MAGNITUDELabel = uilabel(app.GridLayout5);
            app.MAGNITUDELabel.Text = 'MAGNITUDE';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.PHASE_BUTTON = uibutton(app.GridLayout6, 'push');
            app.PHASE_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @PHASE_BUTTONButtonPushed, true);
            app.PHASE_BUTTON.Text = 'Otwórz wykres';
            app.PHASELabel = uilabel(app.GridLayout6);
            app.PHASELabel.Text = 'PHASE';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.ANALOG_BUTTON = uibutton(app.GridLayout7, 'push');
            app.ANALOG_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @ANALOG_BUTTONButtonPushed, true);
            app.ANALOG_BUTTON.Text = 'Otwórz wykres';
            app.ANALOGLabel = uilabel(app.GridLayout7);
            app.ANALOGLabel.Text = 'ANALOG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjektSynchrofazory

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end